classdef XLA_Week10 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UploadButton        matlab.ui.control.Button
        UIAxes_AnhGoc       matlab.ui.control.UIAxes
        UIAxes_AnhNhiPhan   matlab.ui.control.UIAxes
        UIAxes_AnMonAnh     matlab.ui.control.UIAxes
        UIAxes_AnhLamDay    matlab.ui.control.UIAxes
        UIAxes_DongAnh      matlab.ui.control.UIAxes
        UIAxes_MoAnh        matlab.ui.control.UIAxes
        UIAxes_Hit_or_miss  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Description
        map % Description
        colortype % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            f=app.UIFigure;
            [filename,path]=uigetfile('*.*','Chon anh');
            if isequal(filename, 0) || isequal(path,0)
                figure(f);
                uialert(f,'Chua chon anh','Loi doc anh');
                cla(f);
            else
                figure(f);
                file=strcat(path,filename);
                [app.img, app.map]=imread(file);
                imshow(app.img, app.map, 'parent', app.UIAxes_AnhGoc);
               
                info=imfinfo(file);
                app.colortype=info.ColorType;
                if app.colortype=="truecolor"
                   rgb=app.img;
                   gray=rgb2gray(rgb);
                   AnhNhiPhan=imbinarize(gray);
                elseif app.colortype=="indexed"
                   ind=app.img;
                   gray=ind2gray(ind,app.map);
                   AnhNhiPhan=imbinarize(gray);
                else
%                    AnhNhiPhan=imbinarize(app.img); // Vì sao lại bug?
                   AnhNhiPhan=app.img;
                end    
                imshow(AnhNhiPhan,'Parent',app.UIAxes_AnhNhiPhan);
                
                %------
                
                % Ăn mòn ảnh
                sq=ones(3, 3);
                AnhAnMon = imerode(AnhNhiPhan, sq);
                imshow(AnhAnMon,'Parent',app.UIAxes_AnMonAnh);
                
                % Làm dày ảnh
                AnhLamDay = imdilate(AnhNhiPhan, sq);
                imshow(AnhLamDay,'Parent',app.UIAxes_AnhLamDay);
                
                %------
                
                % Đóng Ảnh (Ảnh dày -> Ăn mòn)
                DongAnh = imerode(AnhLamDay, sq);
                imshow(DongAnh,'Parent',app.UIAxes_DongAnh);
                
                % Mở Ảnh (Ảnh ăn mòn -> làm dầy)
                MoAnh = imdilate(AnhAnMon, sq);
                imshow(MoAnh,'Parent',app.UIAxes_MoAnh);
                
                %------
                interval = [0 -1 -1
                            1  1 -1
                            0  1  0];   
                IOM = bwhitmiss(AnhNhiPhan, interval);
                imshow(IOM,'Parent',app.UIAxes_Hit_or_miss);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.UIAxes_AnhGoc = uiaxes(app.UIFigure);
            title(app.UIAxes_AnhGoc, 'Ảnh Gốc')
            app.UIAxes_AnhNhiPhan = uiaxes(app.UIFigure);
            title(app.UIAxes_AnhNhiPhan, 'Ảnh Nhị Phân')
            app.UIAxes_AnMonAnh = uiaxes(app.UIFigure);
            title(app.UIAxes_AnMonAnh, 'Ăn mòn ảnh')
            app.UIAxes_AnhLamDay = uiaxes(app.UIFigure);
            title(app.UIAxes_AnhLamDay, 'Làm dày')
            app.UIAxes_DongAnh = uiaxes(app.UIFigure);
            title(app.UIAxes_DongAnh, 'Đóng ảnh')
            app.UIAxes_MoAnh = uiaxes(app.UIFigure);
            title(app.UIAxes_MoAnh, 'Mở ảnh')
            app.UIAxes_Hit_or_miss = uiaxes(app.UIFigure);
            title(app.UIAxes_Hit_or_miss, 'Hit or miss')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = XLA_Week10

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end