classdef XLA_Week7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        BtnUpload                    matlab.ui.control.Button
        LbTitle                      matlab.ui.control.Label
        nhiuSpinnerLabel             matlab.ui.control.Label
        Spinner_DoNhieu              matlab.ui.control.Spinner
        BtnLocTrungBInh              matlab.ui.control.Button
        LCGAUSSIONButton             matlab.ui.control.Button
        BtnLocTrungVi                matlab.ui.control.Button
        LCKHNGGIANGPHITUYNTNHButton  matlab.ui.control.Button
        DrDo_Noise                   matlab.ui.control.DropDown
        PhngsaiSpinnerLabel          matlab.ui.control.Label
        Spinner_PhuongSai            matlab.ui.control.Spinner
        UIAxes_AnhGoc                matlab.ui.control.UIAxes
        AxesNoise                    matlab.ui.control.UIAxes
        UIAxes_LocTrungBinh          matlab.ui.control.UIAxes
        UIAxes_LocGaussion           matlab.ui.control.UIAxes
        UIAxes_LocTrungVi            matlab.ui.control.UIAxes
        UIAxes_LocXepHangThuTu       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImgAnhGoc % Description
        ImgGray % Description
        ImgMap % Description
        ImgNoise % Description
        TypeNoise % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function BtnUploadButtonPushed(app, event)
            
        end

        % Button pushed function: BtnUpload
        function BtnUploadButtonPushed2(app, event)
            
%             [filename, filepath] = uigetfile('*.*', 'Select File to Open');
%             if isequal(filename, 0) || isequal(filepath,0)
%                 uialert(app.UIFigure, "Chưa đọc ảnh", "Lỗi đọc ảnh");
%                 figure(app.UIFigure);
%             else 
%                 figure(app.UIFigure);
% %                 file=strcat(filepath, filename);
%                 file('twins.tif');
%                 [app.ImgAnhGoc,app.ImgMap]=imread(file); 
%                 
%                 info=imfinfo(file); % Lấy toàn bộ cấu trúc các trường thông tin của ảnh trong tệp ảnh file
%                 colortype=info.ColorType; % Lấy trường thông tin 'ColorType' của ảnh
%                 
%                 % truecolor: Ảnh màu
%                 if colortype == "truecolor" 
%                     app.ImgGray=rgb2gray(app.ImgAnhGoc);
%                     imshow(app.ImgGray,'Parent',app.UIAxes_AnhGoc);
% %                     app.gray_mt=imnoise(app.gray,"salt & pepper",app.Spinner_DoNhieu.Value);
%                     
%                 % indexed: Ảnh nhị phân
%                 elseif colortype=="indexed" 
%                     app.ImgGray=ind2gray(app.ImgAnhGoc,app.ImgMap);
%                     imshow(app.ImgGray,'Parent',app.UIAxes_AnhGoc);
% %                     app.gray_mt=imnoise(app.gray,"salt & pepper",app.Spinner_DoNhieu.Value);
%                     
%                 % grayscale: Ảnh đa mức xám
%                 else 
%                     app.ImgGray=app.img;
%                     imshow(app.ImgGrayImgGray,'Parent',app.UIAxes_AnhGoc);
% %                     app.gray_mt=imnoise(app.gray,"salt & pepper",app.Spinner_DoNhieu.Value);
%                     
%                 end             
%             end

            file = 'twins.tif';
            [app.ImgAnhGoc,app.ImgMap]=imread(file); 
            info=imfinfo(file); % Lấy toàn bộ cấu trúc các trường thông tin của ảnh trong tệp ảnh file
            colortype=info.ColorType; % Lấy trường thông tin 'ColorType' của ảnh
            
            % truecolor: Ảnh màu
            if colortype == "truecolor" 
                app.ImgGray=rgb2gray(app.ImgAnhGoc);
                imshow(app.ImgGray,'Parent',app.UIAxes_AnhGoc);
%                     app.gray_mt=imnoise(app.gray,"salt & pepper",app.Spinner_DoNhieu.Value);
                
            % indexed: Ảnh nhị phân
            elseif colortype=="indexed" 
                app.ImgGray=ind2gray(app.ImgAnhGoc,app.ImgMap);
                imshow(app.ImgGray,'Parent',app.UIAxes_AnhGoc);
%                     app.gray_mt=imnoise(app.gray,"salt & pepper",app.Spinner_DoNhieu.Value);
                
            % grayscale: Ảnh đa mức xám
            else 
                app.ImgGray=app.img;
                imshow(app.ImgGrayImgGray,'Parent',app.UIAxes_AnhGoc);
%                     app.gray_mt=imnoise(app.gray,"salt & pepper",app.Spinner_DoNhieu.Value);
                
            end  
        end

        % Value changing function: Spinner_DoNhieu
        function Spinner_DoNhieuValueChanging(app, event)
            changingValue = event.Value;
            app.ImgNoise = imnoise(app.ImgGray, 'salt & pepper', changingValue);
            imshow(app.ImgNoise, 'Parent', app.AxesNoise);
            
            % Lọc trung binh
            if app.TypeNoise == "Nhiễu Muối tiêu"
                b = fspecial('average', [5, 5]);
                ImgLocTrungBinh = imfilter(app.ImgNoise, b, 'conv');
            elseif app.TypeNoise == "Nhiễu Gaussion"
                AvgF=fspecial('average',[3,3]);
                ImgLocTrungBinh=filter2(AvgF, app.ImgNoise);
            end
            
            imshow(ImgLocTrungBinh, 'Parent', app.UIAxes_LocTrungBinh);
            
            % Lọc gauss
%             g_a3 = fspecial('gaussian', [3,3]);
%             ImgGaussion=filter2(g_a3, app.ImgNoise);
            ImgGaussion = imgaussfilt(app.ImgNoise);
            imshow(ImgGaussion, 'Parent', app.UIAxes_LocGaussion);
            
            % Lọc trung vị
            Img_LocTrungVi=medfilt2(app.ImgNoise);
            imshow(Img_LocTrungVi, 'Parent', app.UIAxes_LocTrungVi);
            
            % Lọc không gian phi tuyến tính
            Img_LocXepHangThuTu=ordfilt2(app.ImgNoise, 5, [0 0 1 0 0;1 1 1 1 1;0 0 1 0 0; 0 0 1 0 0]);
            imshow(Img_LocXepHangThuTu, 'Parent', app.UIAxes_LocXepHangThuTu);
            
        end

        % Button pushed function: BtnLocTrungBInh
        function BtnLocTrungBInhPushed(app, event)
            
        end

        % Button pushed function: BtnLocTrungVi
        function BtnLocTrungViButtonPushed(app, event)
            
        end

        % Button down function: UIAxes_LocTrungBinh
        function UIAxes_LocTrungBinhButtonDown(app, event)
            
        end

        % Button down function: UIAxes_LocGaussion
        function UIAxes_LocGaussionButtonDown(app, event)
            
        end

        % Button pushed function: LCGAUSSIONButton
        function LCGAUSSIONButtonPushed(app, event)
            
        end

        % Button pushed function: LCKHNGGIANGPHITUYNTNHButton
        function LCKHNGGIANGPHITUYNTNHButtonPushed(app, event)
            
        end

        % Button down function: UIAxes_LocXepHangThuTu
        function UIAxes_LocXepHangThuTuButtonDown(app, event)
            
        end

        % Drop down opening function: DrDo_Noise
        function DrDo_NoiseOpening(app, event)
            
        end

        % Value changed function: DrDo_Noise
        function DrDo_NoiseValueChanged(app, event)
            app.TypeNoise = app.DrDo_Noise.Value;
            if app.TypeNoise == "Nhiễu Muối tiêu"
                 app.LbTitle.Text = "NHIỄU MUỐI TIÊU";
                 set(app.Spinner_DoNhieu, 'visible', 'on')
                 set(app.Spinner_PhuongSai, 'visible', 'off')
            elseif app.TypeNoise == "Nhiễu Gaussion"
                app.LbTitle.Text = "NHIỄU GAUSSION";
                set(app.Spinner_DoNhieu, 'visible', 'off')
                 set(app.Spinner_PhuongSai, 'visible', 'on')
            end
        end

        % Value changing function: Spinner_PhuongSai
        function Spinner_PhuongSaiValueChanging(app, event)
            changingValue = event.Value;
            app.ImgNoise = imnoise(app.ImgGray, 'gaussian', changingValue);
            imshow(app.ImgNoise, 'Parent', app.AxesNoise);
            
            % Lọc trung binh
            Img_LocTrungBinh=filter2(fspecial("average",3),app.ImgNoise)/255;
            imshow(Img_LocTrungBinh,'Parent',app.UIAxes_LocTrungBinh);
            
            % Lọc gauss
%             g_a3 = fspecial('gaussian', [3,3]);
%             ImgGaussion=filter2(g_a3, app.ImgNoise);
            ImgGaussion = imgaussfilt(app.ImgNoise);
            imshow(ImgGaussion, 'Parent', app.UIAxes_LocGaussion);
            
            % Lọc trung vị
            Img_LocTrungVi=medfilt2(app.ImgNoise);
            imshow(Img_LocTrungVi, 'Parent', app.UIAxes_LocTrungVi);
            
            % Lọc không gian phi tuyến tính
            Img_LocXepHangThuTu=ordfilt2(app.ImgNoise, 5, [0 0 1 0 0;1 1 1 1 1;0 0 1 0 0; 0 0 1 0 0]);
            imshow(Img_LocXepHangThuTu, 'Parent', app.UIAxes_LocXepHangThuTu);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BtnUpload = uibutton(app.UIFigure, 'push');
            app.BtnUpload.ButtonPushedFcn = createCallbackFcn(app, @BtnUploadButtonPushed2, true);
            app.BtnUpload.Text = 'Upload';
            app.LbTitle = uilabel(app.UIFigure);
            app.LbTitle.Text = 'NHIỄU MUỐI TIÊU';
            app.nhiuSpinnerLabel = uilabel(app.UIFigure);
            app.nhiuSpinnerLabel.Text = 'Độ nhiễu';
            app.Spinner_DoNhieu = uispinner(app.UIFigure);
            app.Spinner_DoNhieu.Step = 0.1;
            app.Spinner_DoNhieu.ValueChangingFcn = createCallbackFcn(app, @Spinner_DoNhieuValueChanging, true);
            app.Spinner_DoNhieu.Limits = [0 1];
            app.Spinner_DoNhieu.Value = 0.1;
            app.BtnLocTrungBInh = uibutton(app.UIFigure, 'push');
            app.BtnLocTrungBInh.ButtonPushedFcn = createCallbackFcn(app, @BtnLocTrungBInhPushed, true);
            app.BtnLocTrungBInh.Visible = 'off';
            app.BtnLocTrungBInh.Text = 'LỌC TRUNG BÌNH';
            app.LCGAUSSIONButton = uibutton(app.UIFigure, 'push');
            app.LCGAUSSIONButton.ButtonPushedFcn = createCallbackFcn(app, @LCGAUSSIONButtonPushed, true);
            app.LCGAUSSIONButton.Visible = 'off';
            app.LCGAUSSIONButton.Text = 'LỌC GAUSSION';
            app.BtnLocTrungVi = uibutton(app.UIFigure, 'push');
            app.BtnLocTrungVi.ButtonPushedFcn = createCallbackFcn(app, @BtnLocTrungViButtonPushed, true);
            app.BtnLocTrungVi.Visible = 'off';
            app.BtnLocTrungVi.Text = 'LỌC TRUNG VỊ';
            app.LCKHNGGIANGPHITUYNTNHButton = uibutton(app.UIFigure, 'push');
            app.LCKHNGGIANGPHITUYNTNHButton.ButtonPushedFcn = createCallbackFcn(app, @LCKHNGGIANGPHITUYNTNHButtonPushed, true);
            app.LCKHNGGIANGPHITUYNTNHButton.Visible = 'off';
            app.LCKHNGGIANGPHITUYNTNHButton.Text = 'LỌC KHÔNG GIANG PHI TUYẾN TÍNH';
            app.DrDo_Noise = uidropdown(app.UIFigure);
            app.DrDo_Noise.Items = {'Nhiễu Muối tiêu', 'Nhiễu Gaussion'};
            app.DrDo_Noise.DropDownOpeningFcn = createCallbackFcn(app, @DrDo_NoiseOpening, true);
            app.DrDo_Noise.ValueChangedFcn = createCallbackFcn(app, @DrDo_NoiseValueChanged, true);
            app.DrDo_Noise.Value = 'Nhiễu Muối tiêu';
            app.PhngsaiSpinnerLabel = uilabel(app.UIFigure);
            app.PhngsaiSpinnerLabel.Visible = 'off';
            app.PhngsaiSpinnerLabel.Text = 'Phương sai';
            app.Spinner_PhuongSai = uispinner(app.UIFigure);
            app.Spinner_PhuongSai.Step = 0.01;
            app.Spinner_PhuongSai.ValueChangingFcn = createCallbackFcn(app, @Spinner_PhuongSaiValueChanging, true);
            app.Spinner_PhuongSai.Limits = [0 1];
            app.Spinner_PhuongSai.Visible = 'off';
            app.UIAxes_AnhGoc = uiaxes(app.UIFigure);
            title(app.UIAxes_AnhGoc, 'Ảnh Gốc')
            app.AxesNoise = uiaxes(app.UIFigure);
            title(app.AxesNoise, 'Ảnh Nhiễu')
            app.UIAxes_LocTrungBinh = uiaxes(app.UIFigure);
            title(app.UIAxes_LocTrungBinh, 'Lọc trung bình')
            app.UIAxes_LocTrungBinh.ButtonDownFcn = createCallbackFcn(app, @UIAxes_LocTrungBinhButtonDown, true);
            app.UIAxes_LocGaussion = uiaxes(app.UIFigure);
            title(app.UIAxes_LocGaussion, 'Lọc Gaussion')
            app.UIAxes_LocGaussion.ButtonDownFcn = createCallbackFcn(app, @UIAxes_LocGaussionButtonDown, true);
            app.UIAxes_LocTrungVi = uiaxes(app.UIFigure);
            title(app.UIAxes_LocTrungVi, 'Lọc trung vị (Median filter)')
            app.UIAxes_LocXepHangThuTu = uiaxes(app.UIFigure);
            title(app.UIAxes_LocXepHangThuTu, 'Lọc không gian phi tuyến tính')
            app.UIAxes_LocXepHangThuTu.ButtonDownFcn = createCallbackFcn(app, @UIAxes_LocXepHangThuTuButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = XLA_Week7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end