classdef XLA_App_Week5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        ConvertRGBButton       matlab.ui.control.Button
        ConvertRGBtoCMYButton  matlab.ui.control.Button
        Btn_UploadImage        matlab.ui.control.Button
        Axes_ImgCMY            matlab.ui.control.UIAxes
        Axes_HistYello         matlab.ui.control.UIAxes
        Axes_HistMagenta       matlab.ui.control.UIAxes
        Axes_HistCyan          matlab.ui.control.UIAxes
        Axes_HistBlue          matlab.ui.control.UIAxes
        Axes_HistGreen         matlab.ui.control.UIAxes
        Axes_HistRed           matlab.ui.control.UIAxes
        Axes_ImgBlue           matlab.ui.control.UIAxes
        Axes_ImgGreen          matlab.ui.control.UIAxes
        Axes_ImgRed            matlab.ui.control.UIAxes
        Axes_AnhGoc            matlab.ui.control.UIAxes
        Axes_ImgCyan           matlab.ui.control.UIAxes
        Axes_ImgMagenta        matlab.ui.control.UIAxes
        Axes_ImgYello          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Description
        ImgR % Description
        ImgG % Description
        ImgB % Description
        ImgC % Description
        ImgM % Description
        ImgY % Description
        RGB % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonPushed(app, event)
            
        end

        % Button pushed function: Btn_UploadImage
        function Btn_UploadImagePushed(app, event)
            [filename, filepath] = uigetfile('*.*', 'Select File to Open');
            if isequal(filename, 0) || isequal(filepath,0)
                uialert(app.UIFigure, "Chưa đọc ảnh", "Lỗi đọc ảnh");
%                 cla(app.Axes_AnhGoc);
                figure(app.UIFigure);
            else 
                figure(app.UIFigure);
                file=strcat(filepath, filename);
                app.img=imread(file);
                info = imfinfo(file); % !!! Phải có, phải nhớ
                colortype=info.ColorType; % !!! Phải có, phải nhớ | ColorType không được sai Color và Types
                if colortype == "truecolor"
                    imshow(app.img, 'Parent', app.Axes_AnhGoc);
                    set(app.ConvertRGBButton, 'visible','on');
                    set(app.ConvertRGBtoCMYButton, 'visible','on');
                else
                    uialert(app.UIFigure, "Ảnh không phải màu thật RGB", "Erorr");
                    figure(app.UIFigure);
                    set(app.ConvertRGBButton, 'visible','off');
                    set(app.ConvertRGBtoCMYButton, 'visible','off');
                end
            end
        end

        % Button pushed function: ConvertRGBButton
        function ConvertRGBButtonPushed(app, event)
            %Img RGB
            app.RGB = app.img;
            
            red_img=app.RGB;
            red_img(:,:,2)=0;
            red_img(:,:,3)=0;
            
            green_img=app.RGB;
            green_img(:,:,1)=0;
            green_img(:,:,3)=0;

            blue_img=app.RGB;
            blue_img(:,:,1)=0;
            blue_img(:,:,2)=0;
                
            % Show Img R-G-B
            imshow(red_img, 'Parent', app.Axes_ImgRed);
            imshow(green_img, 'Parent', app.Axes_ImgGreen);
            imshow(blue_img, 'Parent', app.Axes_ImgBlue);
            
            % Hist RGB
            red_hist = imhist(app.RGB(:,:,1));
            green_hist = imhist(app.RGB(:,:,2));
            blue_hist = imhist(app.RGB(:,:,3));
            
            bar(app.Axes_HistRed, red_hist, 'r');
            bar(app.Axes_HistGreen, green_hist, 'g');
            bar(app.Axes_HistBlue, blue_hist, 'b');
        end

        % Button pushed function: ConvertRGBtoCMYButton
        function ConvertRGBtoCMYButtonPushed(app, event)
            %Img CMY   
            CMY = app.RGB;
            
            CMY(:,:,1) = 255 - app.RGB(:,:,1); % Chỉnh về ảnh CMY
            CMY(:,:,2) = 255 - app.RGB(:,:,2); % Chỉnh về ảnh CMY
            CMY(:,:,3) = 255 - app.RGB(:,:,3); % Chỉnh về ảnh CMY
            
            Cyan = CMY;
            Magenta = CMY;
            Yello = CMY;
            
            Cyan(:,:,1)=0;
            Magenta(:,:,2)=0;
            Yello(:,:,3)=0;
            
            % Show Img CMY
            imshow(CMY, 'Parent', app.Axes_ImgCMY);
    
            % Show Img C-M-Y
            imshow(Cyan, 'Parent', app.Axes_ImgCyan);
            imshow(Magenta, 'Parent', app.Axes_ImgMagenta);
            imshow(Yello, 'Parent', app.Axes_ImgYello);

            % Hist CMY
            cyan_hist = imhist(CMY(:,:,1));
            magenta_hist = imhist(CMY(:,:,2));
            yello_hist = imhist(CMY(:,:,3));
            
            bar(app.Axes_HistCyan, cyan_hist, 'c');
            bar(app.Axes_HistMagenta, magenta_hist, 'm');
            bar(app.Axes_HistYello, yello_hist, 'y');          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ConvertRGBButton = uibutton(app.GridLayout, 'push');
            app.ConvertRGBButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertRGBButtonPushed, true);
            app.ConvertRGBButton.Visible = 'off';
            app.ConvertRGBButton.Text = 'Convert RGB ';
            app.ConvertRGBtoCMYButton = uibutton(app.GridLayout, 'push');
            app.ConvertRGBtoCMYButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertRGBtoCMYButtonPushed, true);
            app.ConvertRGBtoCMYButton.Visible = 'off';
            app.ConvertRGBtoCMYButton.Text = 'Convert RGB to CMY';
            app.Btn_UploadImage = uibutton(app.GridLayout, 'push');
            app.Btn_UploadImage.ButtonPushedFcn = createCallbackFcn(app, @Btn_UploadImagePushed, true);
            app.Btn_UploadImage.Icon = 'Img_Upload.png';
            app.Btn_UploadImage.Text = 'Upload Image';
            app.Axes_ImgCMY = uiaxes(app.GridLayout);
            title(app.Axes_ImgCMY, 'Image CMY')
            app.Axes_HistYello = uiaxes(app.GridLayout);
            title(app.Axes_HistYello, 'Hist Y')
            app.Axes_HistMagenta = uiaxes(app.GridLayout);
            title(app.Axes_HistMagenta, 'Hist M')
            app.Axes_HistCyan = uiaxes(app.GridLayout);
            title(app.Axes_HistCyan, 'Hist C')
            app.Axes_HistBlue = uiaxes(app.GridLayout);
            title(app.Axes_HistBlue, 'Hist B')
            app.Axes_HistGreen = uiaxes(app.GridLayout);
            title(app.Axes_HistGreen, 'Hist G')
            app.Axes_HistRed = uiaxes(app.GridLayout);
            title(app.Axes_HistRed, 'Hist R')
            app.Axes_ImgBlue = uiaxes(app.GridLayout);
            title(app.Axes_ImgBlue, 'B (Blue)')
            app.Axes_ImgGreen = uiaxes(app.GridLayout);
            title(app.Axes_ImgGreen, 'G (Green)')
            app.Axes_ImgRed = uiaxes(app.GridLayout);
            title(app.Axes_ImgRed, 'R (Red)')
            app.Axes_AnhGoc = uiaxes(app.GridLayout);
            title(app.Axes_AnhGoc, 'Ảnh Gốc')
            app.Axes_ImgCyan = uiaxes(app.GridLayout);
            title(app.Axes_ImgCyan, 'C (Cyan)')
            app.Axes_ImgMagenta = uiaxes(app.GridLayout);
            title(app.Axes_ImgMagenta, 'M (Magenta)')
            app.Axes_ImgYello = uiaxes(app.GridLayout);
            title(app.Axes_ImgYello, 'Y (Yellow)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = XLA_App_Week5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end