classdef Baitap2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ChekDepth          matlab.ui.control.Label
        btnAnhNhiPhan      matlab.ui.control.Button
        AnhDaCapXamButton  matlab.ui.control.Button
        btnNhapAnh         matlab.ui.control.Button
        axesAnhNhiPhan     matlab.ui.control.UIAxes
        axesAnhXam         matlab.ui.control.UIAxes
        axesAnhGoc         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Description
        map % Description
        colortype % Description
        bitdepth % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnNhapAnh
        function btnNhapAnhButtonPushed(app, event)
            f=app.UIFigure;
            [filename,path]=uigetfile({'*.png';'*.jpg';'*.tif'},'Chon anh');
            if isequal(filename, 0) || isequal(path,0)
	            figure(f);
   	            uialert(f,'Chua chon anh','Loi doc anh');
            else
                figure(f);
                file=strcat(path, filename);
                [app.img, app.map] = imread(file);
                imshow(app.img, 'Parent', app.axesAnhGoc);
                info = imfinfo(file);
                app.colortype = info.ColorType;
                app.bitdepth = info.BitDepth;
            end
        end

        % Button pushed function: AnhDaCapXamButton
        function AnhDaCapXamButtonPushed(app, event)
            if app.colortype=="truecolor"
               rgb=app.img;
               gray=rgb2gray(rgb);
            elseif app.colortype=="indexed"
               ind=app.img;
               gray=ind2gray(ind,app.map);
            else
               gray = app.img;
            end
            imshow(gray,'Parent',app.axesAnhXam);

        end

        % Button pushed function: btnAnhNhiPhan
        function btnAnhNhiPhanButtonPushed(app, event)
            if app.colortype=="truecolor"
                rgb=app.img;
                gray=rgb2gray(rgb);
                bw=imbinarize(gray);
                imshow(bw, 'Parent', app.axesAnhNhiPhan);
            elseif app.colortype=="grayscale"
                if app.bitdepth == 1
                    bw=app.img;
                    app.ChekDepth.Text="1";
                else
                    app.ChekDepth.Text="Diff";
                    gray = im2double(app.img);
                    bw=imbinarize(gray);
                end
            else
                bw=app.img;
            end
            imshow(bw,'Parent',app.axesAnhNhiPhan);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axesAnhGoc = uiaxes(app.UIFigure);
            title(app.axesAnhGoc, 'AnhGoc')
            app.axesAnhXam = uiaxes(app.UIFigure);
            title(app.axesAnhXam, 'AnhXam')
            app.axesAnhNhiPhan = uiaxes(app.UIFigure);
            title(app.axesAnhNhiPhan, 'AnhNhiPhan')
            app.btnNhapAnh = uibutton(app.UIFigure, 'push');
            app.btnNhapAnh.ButtonPushedFcn = createCallbackFcn(app, @btnNhapAnhButtonPushed, true);
            app.btnNhapAnh.Text = 'NhapAnh';
            app.AnhDaCapXamButton = uibutton(app.UIFigure, 'push');
            app.AnhDaCapXamButton.ButtonPushedFcn = createCallbackFcn(app, @AnhDaCapXamButtonPushed, true);
            app.AnhDaCapXamButton.Text = {'Anh Da Cap Xam'; ''};
            app.btnAnhNhiPhan = uibutton(app.UIFigure, 'push');
            app.btnAnhNhiPhan.ButtonPushedFcn = createCallbackFcn(app, @btnAnhNhiPhanButtonPushed, true);
            app.btnAnhNhiPhan.Text = 'Anh Nhi Phan';
            app.ChekDepth = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Baitap2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end