classdef Baitap9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        BINITUYNTNHLabel   matlab.ui.control.Label
        btnUpload          matlab.ui.control.Button
        axesHistTuyenTinh  matlab.ui.control.UIAxes
        axesHistGoc        matlab.ui.control.UIAxes
        axesAnhTuyenTinh   matlab.ui.control.UIAxes
        axesAnhGoc         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        colortype % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnUpload
        function btnUploadButtonPushed(app, event)
            f=app.UIFigure;
            [filename,path]=uigetfile({'*.png';'*.jpg';'*.tif'},'Chọn tập tin ảnh');
            if isequal(filename,0)||isequal(path,0)
                figure(f);
                uialert(f,' Chưa đọc tập tin ảnh','Lỗi đọc ảnh ');
            else
                figure(f);
                file=strcat(path, filename);
                img = imread(file);
                info = imfinfo(file);
                colortype = info.ColorType;
                bitdepth = info.BitDepth;
                if colortype == "indexed" || bitdepth==1
                    figure(f);
                    uialert(f,'Phải chọn ảnh màu RGB hoặc ảnh xám','Lỗi chọn ảnh')
                    cla(app.axesAnhGoc)
                else
                    imshow(img, 'Parent', app.axesAnhGoc);
                    [pixels,graylevers]=imhist(img);
                    bar(app.axesHistGoc,graylevers,pixels,0.2,"stacked","black");

                    AnhTuyenTinh = imadjust(img, [0.3,0.7],[]);
                    imshow(AnhTuyenTinh, 'Parent', app.axesAnhTuyenTinh)
                    [pixels,graylevers]=imhist(AnhTuyenTinh);
                    bar(app.axesHistTuyenTinh,graylevers,pixels,0.2,"stacked","black");
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axesAnhGoc = uiaxes(app.UIFigure);
            title(app.axesAnhGoc, 'axesAnhGoc')
            app.axesAnhTuyenTinh = uiaxes(app.UIFigure);
            title(app.axesAnhTuyenTinh, 'Anh Cang')
            app.axesHistGoc = uiaxes(app.UIFigure);
            title(app.axesHistGoc, 'HistGoc')
            app.axesHistTuyenTinh = uiaxes(app.UIFigure);
            title(app.axesHistTuyenTinh, 'HistTuyenTinh')
            app.btnUpload = uibutton(app.UIFigure, 'push');
            app.btnUpload.ButtonPushedFcn = createCallbackFcn(app, @btnUploadButtonPushed, true);
            app.btnUpload.Text = 'Upload';
            app.BINITUYNTNHLabel = uilabel(app.UIFigure);
            app.BINITUYNTNHLabel.Text = 'BIẾN ĐỔI TUYẾN TÍNH';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Baitap9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end