classdef BolocLalapcian < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        BT_DOCANH      matlab.ui.control.Button
        XutnhxlButton  matlab.ui.control.Button
        UIAxes         matlab.ui.control.UIAxes
        UIAxesAnhGoc   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        img %anh
        file %file
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BT_DOCANH
        function BT_DOCANHButtonPushed(app, event)
            [filename,path]=uigetfile({'*.jpg';'*.png';'*.tif'},'ChonAnh');
            if isequal(filename,0)||isequal(path,0)
                uialert(app.UIFigure,"Khong doc duoc anh","Loi doc anh")
                cla(app.UIAxesAnhGoc);
            else
                figure(app.UIFigure);
                file=strcat(path,filename);
                app.file=file;
                app.img=imread(file);
                imshow(app.img,'Parent',app.UIAxesAnhGoc);
            end
        end

        % Button pushed function: XutnhxlButton
        function XutnhxlButtonPushed(app, event)
            l=fspecial('laplacian',0);
            imfo=imfinfo(app.file);
            if length(imfo)>1
                imfo=imfo(1);
            end
            img2=app.img;
            if imfo.ColorType=="truecolor"
                img2=rgb2gray(app.img);
            elseif imfo.ColorType=="indexed"
                img2=ind2gray(app.img);
            end
            img3=filter2(l,img2);
            imshow(img3/255,"Parent",app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BT_DOCANH = uibutton(app.UIFigure, 'push');
            app.BT_DOCANH.ButtonPushedFcn = createCallbackFcn(app, @BT_DOCANHButtonPushed, true);
            app.BT_DOCANH.Text = 'Đọc ảnh';
            app.XutnhxlButton = uibutton(app.UIFigure, 'push');
            app.XutnhxlButton.ButtonPushedFcn = createCallbackFcn(app, @XutnhxlButtonPushed, true);
            app.XutnhxlButton.Text = 'Xuất ảnh đã xử lý';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ẢNH ĐƯỢC LỌC BẰNG LAPLACIAN')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxesAnhGoc = uiaxes(app.UIFigure);
            title(app.UIAxesAnhGoc, 'ẢNH GỐC')
            app.UIAxesAnhGoc.XTick = [];
            app.UIAxesAnhGoc.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BolocLalapcian

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end