classdef robert_ngangdoc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        BLCROBERTLabel  matlab.ui.control.Label
        TchbinButton    matlab.ui.control.Button
        cnhButton       matlab.ui.control.Button
        BindcLabel      matlab.ui.control.Label
        KtquLabel       matlab.ui.control.Label
        BinngangLabel   matlab.ui.control.Label
        nhgcLabel       matlab.ui.control.Label
        axe_kq          matlab.ui.control.UIAxes
        axe_doc         matlab.ui.control.UIAxes
        axe_ngang       matlab.ui.control.UIAxes
        axe_anhgoc      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img
        map
        colortype % Description
        gray
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: cnhButton
        function cnhButtonPushed(app, event)
          f=app.UIFigure;
         [filename,path]=uigetfile({'*.png';'*.tif';'*.jpg'},"chọn ảnh");
         if isequal(filename,0) || isequal(path,0)
             uialert(f,"chưa chọn ảnh","lỗi đọc ảnh");
         else
             file=strcat(path,filename);
             [app.img,app.map]=imread(file);
             info=imfinfo(file);
             app.colortype=info.ColorType;
             if app.colortype=="truecolor" 
                 rgb=app.img;
                 app.gray=rgb2gray(rgb);
                 imshow(app.gray,'Parent',app.axe_anhgoc);
             elseif app.colortype=="indexed"
                 ind=app.img;
                 app.gray=ind2gray(ind,app.map);
                 imshow(app.gray,'Parent',app.axe_anhgoc);
             else
                 app.gray=app.img;
                 imshow(app.img,'Parent',app.axe_anhgoc);
             end
         end
        end

        % Button pushed function: TchbinButton
        function TchbinButtonPushed(app, event)
            ro_x=[1 0 ; 0 -1];
            gx=filter2(ro_x, app.gray);
            imshow(gx/255,'Parent',app.axe_ngang);
            ro_y =[0 1 ; -1 0] ;
            gy=filter2(ro_y,app.gray);
            imshow(gy/255,'Parent',app.axe_doc); 
            kq=sqrt(gx.^2 + gy.^2);
            imshow(kq/255,'Parent',app.axe_kq)
        end

        % Callback function
        function TchbindcButtonPushed(app, event)
                      
        end

        % Callback function
        function KtquButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axe_anhgoc = uiaxes(app.UIFigure);
            xlabel(app.axe_anhgoc, 'X')
            ylabel(app.axe_anhgoc, 'Y')
            zlabel(app.axe_anhgoc, 'Z')
            app.axe_ngang = uiaxes(app.UIFigure);
            xlabel(app.axe_ngang, 'X')
            ylabel(app.axe_ngang, 'Y')
            zlabel(app.axe_ngang, 'Z')
            app.axe_doc = uiaxes(app.UIFigure);
            xlabel(app.axe_doc, 'X')
            ylabel(app.axe_doc, 'Y')
            zlabel(app.axe_doc, 'Z')
            app.axe_kq = uiaxes(app.UIFigure);
            xlabel(app.axe_kq, 'X')
            ylabel(app.axe_kq, 'Y')
            zlabel(app.axe_kq, 'Z')
            app.nhgcLabel = uilabel(app.UIFigure);
            app.nhgcLabel.Text = 'Ảnh gốc';
            app.BinngangLabel = uilabel(app.UIFigure);
            app.BinngangLabel.Text = {'Biên ngang'; ''};
            app.KtquLabel = uilabel(app.UIFigure);
            app.KtquLabel.Text = {'Kết quả'; ''};
            app.BindcLabel = uilabel(app.UIFigure);
            app.BindcLabel.Text = {'Biên dọc'; ''};
            app.cnhButton = uibutton(app.UIFigure, 'push');
            app.cnhButton.ButtonPushedFcn = createCallbackFcn(app, @cnhButtonPushed, true);
            app.cnhButton.Text = {'Đọc ảnh'; ''};
            app.TchbinButton = uibutton(app.UIFigure, 'push');
            app.TchbinButton.ButtonPushedFcn = createCallbackFcn(app, @TchbinButtonPushed, true);
            app.TchbinButton.Text = {'Tách biên'; ''; ''};
            app.BLCROBERTLabel = uilabel(app.UIFigure);
            app.BLCROBERTLabel.Text = 'BỘ LỌC ROBERT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robert_ngangdoc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end