classdef ThaoTacCoBanTrenAnh < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        lb_main               matlab.ui.control.Label
        THNGTINTextAreaLabel  matlab.ui.control.Label
        txtInfo               matlab.ui.control.TextArea
        btn_upload            matlab.ui.control.Button
        btnDacapxam           matlab.ui.control.Button
        btnAnhnhiphan         matlab.ui.control.Button
        Axes_AnhGoc           matlab.ui.control.UIAxes
        Axes_AnhDacapxam      matlab.ui.control.UIAxes
        Axes_Anhnhiphan       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btn_upload
        function btn_uploadButtonPushed(app, event)
            [filename, path] = uigetfile('*', "Chọn ảnh");
            if isequal(filename, 0) || isequal(path, 0)
               uialert(app.UIFigure, "Chưa đọc ảnh", "Lỗi đọc ảnh");
               cla(app.UIAxesAnhGoc);
            else 
                file = strcat(path, filename);
                app.img = imread(file);
                imshow(app.img, 'Parent', app.Axes_AnhGoc);
                
                info=imfinfo(file);
                
                arr=[];
                
                if length(info)>1
                    app.txtInfo.Value=strcat("Tập tin chứa: ", num2str(length(info)), " ảnh");
                    arr=[arr; "THÔNG TIN ẢNH ĐẦU TIN"];
                    info=info(1);
                else
                    app.txtInfo.Value = "";
                end
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.lb_main = uilabel(app.UIFigure);
            app.lb_main.Text = 'THAO TÁC CƠ BẢN TRÊN ẢNH';
            app.THNGTINTextAreaLabel = uilabel(app.UIFigure);
            app.THNGTINTextAreaLabel.Text = 'THÔNG TIN';
            app.txtInfo = uitextarea(app.UIFigure);
            app.btn_upload = uibutton(app.UIFigure, 'push');
            app.btn_upload.ButtonPushedFcn = createCallbackFcn(app, @btn_uploadButtonPushed, true);
            app.btn_upload.Text = 'Đọc ảnh';
            app.btnDacapxam = uibutton(app.UIFigure, 'push');
            app.btnDacapxam.Text = 'Chuyển ảnh đa cấp xám';
            app.btnAnhnhiphan = uibutton(app.UIFigure, 'push');
            app.btnAnhnhiphan.Text = 'Chuyển ảnh nhị phân';
            app.Axes_AnhGoc = uiaxes(app.UIFigure);
            title(app.Axes_AnhGoc, 'Ảnh Gốc')
            app.Axes_AnhDacapxam = uiaxes(app.UIFigure);
            title(app.Axes_AnhDacapxam, {'Ảnh Đa cấp xám'; ''})
            app.Axes_Anhnhiphan = uiaxes(app.UIFigure);
            title(app.Axes_Anhnhiphan, 'Ảnh Nhị phân')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThaoTacCoBanTrenAnh

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end