classdef ThietKeGiaoDien < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        btn_Upload    matlab.ui.control.Button
        UIAxesAnhGoc  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btn_Upload
        function btn_UploadButtonPushed(app, event)
            [filename, path] = uigetfile('*', "Chọn ảnh");
            if isequal(filename, 0) || isequal(path, 0)
               uialert(app.UIFigure, "Chưa đọc ảnh", "Lỗi đọc ảnh");
               cla(app.UIAxesAnhGoc);
            else 
                file = strcat(path, filename);
                img = imread(file);
                imshow(img, 'Parent', app.UIAxesAnhGoc);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.btn_Upload = uibutton(app.UIFigure, 'push');
            app.btn_Upload.ButtonPushedFcn = createCallbackFcn(app, @btn_UploadButtonPushed, true);
            app.btn_Upload.Text = 'Đọc ảnh';
            app.UIAxesAnhGoc = uiaxes(app.UIFigure);
            title(app.UIAxesAnhGoc, 'AnhGoc')
            xlabel(app.UIAxesAnhGoc, 'X')
            ylabel(app.UIAxesAnhGoc, 'Y')
            zlabel(app.UIAxesAnhGoc, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThietKeGiaoDien

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end