classdef Baitap1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        HISTOGRAMLabel  matlab.ui.control.Label
        btnUpload       matlab.ui.control.Button
        btnHistogram    matlab.ui.control.Button
        AxesAnhGoc      matlab.ui.control.UIAxes
        AxesHistogram   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Description
        map % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnUpload
        function btnUploadButtonPushed(app, event)
            f=app.UIFigure;
            [filename,path]=uigetfile({'*.png;*.jpg;*.tif'},"chọn ảnh");
            if isequal(filename,0)||isequal(path,0)
                uialert(f,"Chưa đọc ảnh","Lỗi đọc ảnh");
                cla(app.UIAnhGoc);
            else
                file=strcat(path,filename);
                app.img=imread(file);
                imshow(app.img,'Parent',app.AxesAnhGoc);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HISTOGRAMLabel = uilabel(app.UIFigure);
            app.HISTOGRAMLabel.Text = 'HISTOGRAM';
            app.btnUpload = uibutton(app.UIFigure, 'push');
            app.btnUpload.ButtonPushedFcn = createCallbackFcn(app, @btnUploadButtonPushed, true);
            app.btnUpload.Text = 'Đọc ảnh';
            app.btnHistogram = uibutton(app.UIFigure, 'push');
            app.btnHistogram.Text = 'Xuất Histogram';
            app.AxesAnhGoc = uiaxes(app.UIFigure);
            title(app.AxesAnhGoc, 'Ảnh xám')
            app.AxesHistogram = uiaxes(app.UIFigure);
            title(app.AxesHistogram, 'Histogram')
            xlabel(app.AxesHistogram, 'Mức xám')
            ylabel(app.AxesHistogram, 'Số điểm ảnh')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Baitap1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end