classdef Baitap_Xulyamban < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        LYMBNNHACPXMLabel  matlab.ui.control.Label
        btnXuly            matlab.ui.control.Button
        AxesAnhgoc         matlab.ui.control.UIAxes
        AxesDacapxam       matlab.ui.control.UIAxes
        AxesAmban          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        bitdepth
        colortype
        gray % Description
    end
    
    methods (Access = private)
        function out = layamban(app)
            if app.colortype=="truecolor"
                app.gray=rgb2gray(app.img);
                app.bitdepth=app.bitdepth/3;
            elseif app.colortype=="indexed"
                app.gray=ind2gray(app.img);
            else
                app.gray=app.img;
            end
            [y, x]=size(app.gray);
            out=zeros(y, x);
            Lmax=2^app.bitdepth-1;
            for i=1:y
                for j=1:x
                    out(i, j)=Lmax-app.gray(i, j);
                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnXuly
        function btnXulyButtonPushed(app, event)
            [filename,path]=uigetfile({'*.png';'*.jpg';'*.tif'},"chọn ảnh");
            if isequal(filename,0)||isequal(path,0)
                uialert(app.UIFigure,"Chưa đọc ảnh","Lỗi đọc ảnh");
                cla(app.UIAnhGoc);
            else
                file=strcat(path,filename);
                img=imread(file);
                imshow(img,'Parent',app.AxesAnhgoc);
                
                info=imfinfo(file);
                app.colortype=info.ColorType;

                out=app.layamban(app);
                imshow(app.gray,[],'Parent', app.AxesDacapxam);
                imshow(out, [], 'Parent', app.AxesAmban);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LYMBNNHACPXMLabel = uilabel(app.UIFigure);
            app.LYMBNNHACPXMLabel.Text = 'LẤY ÂM BẢN ẢNH ĐA CẤP XÁM';
            app.btnXuly = uibutton(app.UIFigure, 'push');
            app.btnXuly.ButtonPushedFcn = createCallbackFcn(app, @btnXulyButtonPushed, true);
            app.btnXuly.Text = 'Đọc ảnh và xử lý';
            app.AxesAnhgoc = uiaxes(app.UIFigure);
            title(app.AxesAnhgoc, 'Ảnh Gốc')
            app.AxesDacapxam = uiaxes(app.UIFigure);
            title(app.AxesDacapxam, 'Ảnh đa cấp xám')
            app.AxesAmban = uiaxes(app.UIFigure);
            title(app.AxesAmban, 'Ảnh âm bản')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Baitap_Xulyamban

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end