classdef XLA_Thuchanh5_Tuan10 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        BtnUpload1            matlab.ui.control.Button
        BtnUpload2            matlab.ui.control.Button
        BtnSameSize           matlab.ui.control.Button
        GitrnhnSpinnerLabel   matlab.ui.control.Label
        Spinner_GiatriNhan    matlab.ui.control.Spinner
        GitrchiaSpinnerLabel  matlab.ui.control.Label
        Spinner_GiatriChia    matlab.ui.control.Spinner
        Btn_Cong              matlab.ui.control.Button
        Btn_Tru               matlab.ui.control.Button
        Btn_Nhan              matlab.ui.control.Button
        Btn_Chia              matlab.ui.control.Button
        Lb_AnhGoc1            matlab.ui.control.Label
        Lb_AnhGoc2            matlab.ui.control.Label
        Lb_AnhResize1         matlab.ui.control.Label
        Lb_AnhResize2         matlab.ui.control.Label
        UIAxes_AnhGoc1        matlab.ui.control.UIAxes
        UIAxes_AnhGoc2        matlab.ui.control.UIAxes
        UIAxes_AnhResize1     matlab.ui.control.UIAxes
        UIAxes_AnhResize2     matlab.ui.control.UIAxes
        UIAxes_AnhCong        matlab.ui.control.UIAxes
        UIAxes_AnhTru         matlab.ui.control.UIAxes
        UIAxes_AnhNhan        matlab.ui.control.UIAxes
        UIAxes_AnhChia        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImgAnhGoc1 % Description
        ImgAnhGoc2 % Description
        ImgAnhResize1 % Description
        ImgAnhResize2 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function UploadImage1ButtonPushed(app, event)
            
        end

        % Button pushed function: BtnUpload1
        function BtnUpload1ButtonPushed(app, event)
            try
                [filename, filepath] = uigetfile('*.*', 'Select File to Open');
            catch ME
                warning('File selection was interrupted.');
                filename = [];
                filepath = [];
            end
            if isequal(filename, 0) || isequal(filepath,0)
                uialert(app.UIFigure, "Chưa đọc ảnh", "Lỗi đọc ảnh");
                figure(app.UIFigure);
            else 
                figure(app.UIFigure);
                file=strcat(filepath, filename);
                app.ImgAnhGoc1=imread(file);
                imshow(app.ImgAnhGoc1, 'Parent', app.UIAxes_AnhGoc1);
                set(app.BtnUpload2, 'visible','on');
                
                set(app.Lb_AnhGoc1, 'visible', 'on')
                w_img = width(app.ImgAnhGoc1);
                h_img = height(app.ImgAnhGoc1);
                app.Lb_AnhGoc1.Text = "Width: "+w_img+" | Height:"+h_img;
            end
        end

        % Button pushed function: BtnUpload2
        function BtnUpload2ButtonPushed(app, event)
            [filename, filepath] = uigetfile('*.*', 'Select File to Open');
            if isequal(filename, 0) || isequal(filepath,0)
                uialert(app.UIFigure, "Chưa đọc ảnh", "Lỗi đọc ảnh");
                figure(app.UIFigure);
            else 
                figure(app.UIFigure);
                file=strcat(filepath, filename);
                app.ImgAnhGoc2=imread(file);
                imshow(app.ImgAnhGoc2, 'Parent', app.UIAxes_AnhGoc2);
                set(app.BtnSameSize, 'visible','on');
                
                set(app.Lb_AnhGoc2, 'visible', 'on')
                w_img = width(app.ImgAnhGoc2);
                h_img = height(app.ImgAnhGoc2);
                app.Lb_AnhGoc2.Text = "Width: "+w_img+" | Height:"+h_img;
            end
        end

        % Button pushed function: BtnSameSize
        function BtnSameSizeButtonPushed(app, event)
           img1w = width(app.ImgAnhGoc1);
           img1h = height(app.ImgAnhGoc1);
           img2w = width(app.ImgAnhGoc2);
           img2h = height(app.ImgAnhGoc2);
           
           if(img1w < img2w)
               maxw = img2w;
           else
               maxw = img1w;
           end
           
           if(img1h < img2h)
               maxh = img2h;
           else
               maxh = img1h;
           end
           
           app.ImgAnhResize1 = imresize(app.ImgAnhGoc1,[maxh, maxw]);
           app.ImgAnhResize2 = imresize(app.ImgAnhGoc2,[maxh, maxw]);
           
           % Hiện thông số
           set(app.Lb_AnhResize1, 'visible', 'on')
           w_img = width(app.ImgAnhResize1);
           h_img = height(app.ImgAnhResize1);
           app.Lb_AnhResize1.Text = "Width: "+w_img+" | Height:"+h_img;
           
           set(app.Lb_AnhResize2, 'visible', 'on')
           w_img2 = width(app.ImgAnhResize2);
           h_img2 = height(app.ImgAnhResize2);
           app.Lb_AnhResize2.Text = "Width: "+w_img2+" | Height:"+h_img2;
            
           
           imshow(app.ImgAnhResize1, 'Parent', app.UIAxes_AnhResize1);
           imshow(app.ImgAnhResize2, 'Parent', app.UIAxes_AnhResize2);
         
            set(app.Btn_Cong, 'visible','on');
            set(app.Btn_Tru, 'visible','on');
            set(app.Btn_Nhan, 'visible','on');
            set(app.Btn_Chia, 'visible','on');
        end

        % Button pushed function: Btn_Cong
        function Btn_CongButtonPushed(app, event)
            ImgCong = imadd(app.ImgAnhResize1, app.ImgAnhResize2);
            imshow(ImgCong, 'Parent', app.UIAxes_AnhCong);
        end

        % Button pushed function: Btn_Tru
        function Btn_TruButtonPushed(app, event)
            ImgTru = imsubtract(app.ImgAnhResize1, app.ImgAnhResize2);
            imshow(ImgTru, 'Parent', app.UIAxes_AnhTru);
        end

        % Button pushed function: Btn_Nhan
        function Btn_NhanButtonPushed(app, event)
            ImgNhan = immultiply(app.ImgAnhResize1, app.Spinner_GiatriNhan.Value);
            imshow(ImgNhan, 'Parent', app.UIAxes_AnhNhan);
        end

        % Button pushed function: Btn_Chia
        function Btn_ChiaButtonPushed(app, event)
            ImgChia = imdivide(app.ImgAnhResize1, app.Spinner_GiatriChia.Value);
            imshow(ImgChia, 'Parent', app.UIAxes_AnhChia);
        end

        % Value changing function: Spinner_GiatriNhan
        function Spinner_GiatriNhanValueChanging(app, event)
            changingValue = event.Value;
            ImgNhan = immultiply(app.ImgAnhResize1, changingValue);
            imshow(ImgNhan, 'Parent', app.UIAxes_AnhNhan);
        end

        % Value changing function: Spinner_GiatriChia
        function Spinner_GiatriChiaValueChanging(app, event)
            changingValue = event.Value;
            ImgChia = imdivide(app.ImgAnhResize1, changingValue);
            imshow(ImgChia, 'Parent', app.UIAxes_AnhChia);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.BtnUpload1 = uibutton(app.GridLayout, 'push');
            app.BtnUpload1.ButtonPushedFcn = createCallbackFcn(app, @BtnUpload1ButtonPushed, true);
            app.BtnUpload1.Text = 'Upload Image 1';
            app.BtnUpload2 = uibutton(app.GridLayout, 'push');
            app.BtnUpload2.ButtonPushedFcn = createCallbackFcn(app, @BtnUpload2ButtonPushed, true);
            app.BtnUpload2.Visible = 'off';
            app.BtnUpload2.Text = 'Upload Image 2';
            app.BtnSameSize = uibutton(app.GridLayout, 'push');
            app.BtnSameSize.ButtonPushedFcn = createCallbackFcn(app, @BtnSameSizeButtonPushed, true);
            app.BtnSameSize.Visible = 'off';
            app.BtnSameSize.Text = {'Same Size'; ''};
            app.GitrnhnSpinnerLabel = uilabel(app.GridLayout);
            app.GitrnhnSpinnerLabel.Text = 'Giá trị nhân';
            app.Spinner_GiatriNhan = uispinner(app.GridLayout);
            app.Spinner_GiatriNhan.Step = 0.1;
            app.Spinner_GiatriNhan.ValueChangingFcn = createCallbackFcn(app, @Spinner_GiatriNhanValueChanging, true);
            app.GitrchiaSpinnerLabel = uilabel(app.GridLayout);
            app.GitrchiaSpinnerLabel.Text = 'Giá trị chia';
            app.Spinner_GiatriChia = uispinner(app.GridLayout);
            app.Spinner_GiatriChia.Step = 0.1;
            app.Spinner_GiatriChia.ValueChangingFcn = createCallbackFcn(app, @Spinner_GiatriChiaValueChanging, true);
            app.Btn_Cong = uibutton(app.GridLayout, 'push');
            app.Btn_Cong.ButtonPushedFcn = createCallbackFcn(app, @Btn_CongButtonPushed, true);
            app.Btn_Cong.Visible = 'off';
            app.Btn_Cong.Text = '+';
            app.Btn_Tru = uibutton(app.GridLayout, 'push');
            app.Btn_Tru.ButtonPushedFcn = createCallbackFcn(app, @Btn_TruButtonPushed, true);
            app.Btn_Tru.Visible = 'off';
            app.Btn_Tru.Text = '-';
            app.Btn_Nhan = uibutton(app.GridLayout, 'push');
            app.Btn_Nhan.ButtonPushedFcn = createCallbackFcn(app, @Btn_NhanButtonPushed, true);
            app.Btn_Nhan.Visible = 'off';
            app.Btn_Nhan.Text = 'X';
            app.Btn_Chia = uibutton(app.GridLayout, 'push');
            app.Btn_Chia.ButtonPushedFcn = createCallbackFcn(app, @Btn_ChiaButtonPushed, true);
            app.Btn_Chia.Visible = 'off';
            app.Btn_Chia.Text = '/';
            app.Lb_AnhGoc1 = uilabel(app.GridLayout);
            app.Lb_AnhGoc1.Visible = 'off';
            app.Lb_AnhGoc2 = uilabel(app.GridLayout);
            app.Lb_AnhGoc2.Visible = 'off';
            app.Lb_AnhGoc2.Text = 'Label2';
            app.Lb_AnhResize1 = uilabel(app.GridLayout);
            app.Lb_AnhResize1.Visible = 'off';
            app.Lb_AnhResize1.Text = 'Label3';
            app.Lb_AnhResize2 = uilabel(app.GridLayout);
            app.Lb_AnhResize2.Visible = 'off';
            app.Lb_AnhResize2.Text = 'Label4';
            app.UIAxes_AnhGoc1 = uiaxes(app.GridLayout);
            title(app.UIAxes_AnhGoc1, 'Ảnh Gốc 1')
            app.UIAxes_AnhGoc2 = uiaxes(app.GridLayout);
            title(app.UIAxes_AnhGoc2, 'Ảnh Gốc 2')
            app.UIAxes_AnhResize1 = uiaxes(app.GridLayout);
            title(app.UIAxes_AnhResize1, 'Ảnh Resize 1')
            app.UIAxes_AnhResize2 = uiaxes(app.GridLayout);
            title(app.UIAxes_AnhResize2, 'Ảnh Resize 2')
            app.UIAxes_AnhCong = uiaxes(app.GridLayout);
            title(app.UIAxes_AnhCong, 'Cộng')
            app.UIAxes_AnhTru = uiaxes(app.GridLayout);
            title(app.UIAxes_AnhTru, 'Trừ')
            app.UIAxes_AnhNhan = uiaxes(app.GridLayout);
            title(app.UIAxes_AnhNhan, 'Nhân')
            app.UIAxes_AnhChia = uiaxes(app.GridLayout);
            title(app.UIAxes_AnhChia, 'Chia')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = XLA_Thuchanh5_Tuan10

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end