classdef XLA_Week8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        Btn_UploadImg        matlab.ui.control.Button
        UIAxes_Sobel_y       matlab.ui.control.UIAxes
        UIAxes_Sobel_x       matlab.ui.control.UIAxes
        UIAxes_Prewitt_y     matlab.ui.control.UIAxes
        UIAxes_Prewitt_x     matlab.ui.control.UIAxes
        UIAxes_AnhRobert_y   matlab.ui.control.UIAxes
        UIAxes_AnhRobert_x   matlab.ui.control.UIAxes
        UIAxes_AnhSobel      matlab.ui.control.UIAxes
        UIAxes_AnhPrewitt    matlab.ui.control.UIAxes
        UIAxes_AnhRobert     matlab.ui.control.UIAxes
        UIAxes_AnhLalapcian  matlab.ui.control.UIAxes
        UIAxes_AnhGoc        matlab.ui.control.UIAxes
        UIAxes_AnhXam        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        AnhGoc % Description
        ImgMap % Description
        AnhXam % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Btn_UploadImg
        function Btn_UploadImgButtonPushed(app, event)
            f = app.UIFigure;
            [filename, filepath] = uigetfile({'*.png';'*.jpg';'*.tif'}, 'Select File to Open');
            if isequal(filename, 0) || isequal(filepath,0)
                uialert(f, "Chưa đọc ảnh", "Lỗi đọc ảnh");
                figure(f);
            else 
                figure(f);
                file=strcat(filepath, filename);
                [app.AnhGoc,app.ImgMap]=imread(file); 
                info=imfinfo(file); % Lấy toàn bộ cấu trúc các trường thông tin của ảnh trong tệp ảnh file
                colortype=info.ColorType; % Lấy trường thông tin 'ColorType' của ảnh
                
                imshow(app.AnhGoc, 'Parent', app.UIAxes_AnhGoc);
                
                % truecolor: Ảnh màu
                if colortype == "truecolor" 
                    app.AnhXam=rgb2gray(app.AnhGoc);
                % indexed: Ảnh nhị phân
                elseif colortype=="indexed" 
                    app.AnhXam=ind2gray(app.AnhGoc,app.ImgMap);
                % grayscale: Ảnh đa mức xám
                else 
                    app.AnhXam=app.AnhGoc;
                end   
                
                imshow(app.AnhXam,'Parent',app.UIAxes_AnhXam);
                
                % Lalapcian
                l=fspecial('laplacian',0);
                img3=filter2(l,app.AnhXam);
                imshow(img3/255,'Parent',app.UIAxes_AnhLalapcian);
                
                % Robert:
                ro_x=[1 0 ; 0 -1];
                gx=filter2(ro_x, app.AnhXam);
                imshow(gx/255,'Parent',app.UIAxes_AnhRobert_x);
                ro_y =[0 1 ; -1 0] ;
                gy=filter2(ro_y,app.AnhXam);
                imshow(gy/255,'Parent',app.UIAxes_AnhRobert_y); 
                kq=sqrt(gx.^2 + gy.^2);
                imshow(kq/255,'Parent',app.UIAxes_AnhRobert);
                
                % Prewitt:
                pr_x=[-1 0 1; -1 0 1; -1 0 1];
                gx=filter2(pr_x, app.AnhXam);
                imshow(gx/255,'Parent',app.UIAxes_Prewitt_x);
                pr_y =[-1 -1 -1; 0 0 0; 1 1 1] ;
                gy=filter2(pr_y,app.AnhXam);
                imshow(gy/255,'Parent',app.UIAxes_Prewitt_y); 
                kq=sqrt(gx.^2 + gy.^2);
                imshow(kq/255,'Parent',app.UIAxes_AnhPrewitt);

                % Sobel
                so_x=[-1 0 1; -2 0 2; -1 0 1];
                gx=filter2(so_x, app.AnhXam);
                imshow(gx/255,'Parent',app.UIAxes_Sobel_x);
                so_y =[-1 -2 -1; 0 0 0; 1 2 1] ;
                gy=filter2(so_y,app.AnhXam);
                imshow(gy/255,'Parent',app.UIAxes_Sobel_y); 
                kq=sqrt(gx.^2 + gy.^2);
                imshow(kq/255,'Parent',app.UIAxes_AnhSobel);

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Btn_UploadImg = uibutton(app.GridLayout, 'push');
            app.Btn_UploadImg.ButtonPushedFcn = createCallbackFcn(app, @Btn_UploadImgButtonPushed, true);
            app.Btn_UploadImg.Text = 'Upload';
            app.UIAxes_Sobel_y = uiaxes(app.GridLayout);
            title(app.UIAxes_Sobel_y, 'Oy - Sobel')
            app.UIAxes_Sobel_x = uiaxes(app.GridLayout);
            title(app.UIAxes_Sobel_x, 'Ox - Sobel')
            app.UIAxes_Prewitt_y = uiaxes(app.GridLayout);
            title(app.UIAxes_Prewitt_y, 'Oy - Prewitt')
            app.UIAxes_Prewitt_x = uiaxes(app.GridLayout);
            title(app.UIAxes_Prewitt_x, 'Ox - Prewitt')
            app.UIAxes_AnhRobert_y = uiaxes(app.GridLayout);
            title(app.UIAxes_AnhRobert_y, 'Oy - Robert')
            app.UIAxes_AnhRobert_x = uiaxes(app.GridLayout);
            title(app.UIAxes_AnhRobert_x, 'Ox - Robert')
            app.UIAxes_AnhSobel = uiaxes(app.GridLayout);
            title(app.UIAxes_AnhSobel, 'Sobel')
            app.UIAxes_AnhPrewitt = uiaxes(app.GridLayout);
            title(app.UIAxes_AnhPrewitt, 'Prewitt')
            app.UIAxes_AnhRobert = uiaxes(app.GridLayout);
            title(app.UIAxes_AnhRobert, 'Robert')
            app.UIAxes_AnhLalapcian = uiaxes(app.GridLayout);
            title(app.UIAxes_AnhLalapcian, 'Lalapcian')
            app.UIAxes_AnhGoc = uiaxes(app.GridLayout);
            title(app.UIAxes_AnhGoc, 'Ảnh Gốc')
            app.UIAxes_AnhXam = uiaxes(app.GridLayout);
            title(app.UIAxes_AnhXam, 'Ảnh Xám')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = XLA_Week8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end