classdef Baitap1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        lblThongTin  matlab.ui.control.Label
        txtThongTin  matlab.ui.control.TextArea
        btnNhapAnh   matlab.ui.control.Button
        axesAnhGoc   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Description
        map % Description
        Text % Description
        colortype % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnNhapAnh
        function btnNhapAnhButtonPushed(app, event)
            f=app.UIFigure;
            [filename, path] = uigetfile({'*.png';'*.jpg';'*.tif'}, 'Chọn ảnh');
            if isequal(filename, 0) || isequal(path, 0)
                figure(f)
                uialert(f,'Chưa chọn ảnh', 'Lỗi đọc ảnh');
                cla(f);
            else
                figure(f);
            end

            file=strcat(path, filename);
            [app.img, app.map]=imread(file);
            imshow(app.img, app.map, 'Parent', app.axesAnhGoc);
            info=imfinfo(file);
            arr=[];
            if length(info) > 1
                app.lblThongTin.Text=strcat(strcat("Tập tin chứa: ", num2str(length(info))), " ảnh");
                arr=[arr, "THÔNG TIN ẢNH"];
                info=info(1);
            else
                app.lblThongTin.Text=" ";
            end

            fields=fieldnames(info);
            for i=1:length(fields)
                if isnumeric(info.(char(fields(i))))
                    value=num2str(info.(char(fields(i))));
                else
                    value=info.(char(fields(i)));
                end
                str=strcat(fields(i),": ", value)
                arr=[arr;str];
                arr=[arr;'---------------------'];
            end
            app.txtThongTin.Value=arr;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axesAnhGoc = uiaxes(app.UIFigure);
            title(app.axesAnhGoc, 'Ảnh Gốc')
            app.btnNhapAnh = uibutton(app.UIFigure, 'push');
            app.btnNhapAnh.ButtonPushedFcn = createCallbackFcn(app, @btnNhapAnhButtonPushed, true);
            app.btnNhapAnh.Text = 'Nhập ảnh';
            app.txtThongTin = uitextarea(app.UIFigure);
            app.lblThongTin = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Baitap1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end