classdef Baitap5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        btnUpload      matlab.ui.control.Button
        axesNguongDon  matlab.ui.control.UIAxes
        axesAmBang     matlab.ui.control.UIAxes
        axesAnhGoc     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnUpload
        function btnUploadButtonPushed(app, event)
            f=app.UIFigure;
            [filename,path]=uigetfile({'*.png';'*.jpg';'*.tif'},'Chon anh');
            if isequal(filename, 0) || isequal(path,0)
	            figure(f);
   	            uialert(f,'Chua chon anh','Loi doc anh');
            else
                figure(f);
                file=strcat(path, filename);
                app.img = imread(file);
                imshow(app.img, 'Parent', app.axesAnhGoc);

                AnhAmBang = imcomplement(app.img);
                imshow(AnhAmBang, 'Parent', app.axesAmBang);
                
                threshold = graythresh(app.img);
                NguongDon = imbinarize(app.img, threshold);
                imshow(NguongDon, 'Parent', app.axesNguongDon);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axesAnhGoc = uiaxes(app.UIFigure);
            title(app.axesAnhGoc, 'axesAnhGoc')
            app.axesAmBang = uiaxes(app.UIFigure);
            title(app.axesAmBang, 'axesAmBang')
            app.axesNguongDon = uiaxes(app.UIFigure);
            title(app.axesNguongDon, 'axesNguongDon')
            app.btnUpload = uibutton(app.UIFigure, 'push');
            app.btnUpload.ButtonPushedFcn = createCallbackFcn(app, @btnUploadButtonPushed, true);
            app.btnUpload.Text = 'Upload';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Baitap5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end