classdef Baitap6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        btnUpload   matlab.ui.control.Button
        axesHist    matlab.ui.control.UIAxes
        axesAnhXam  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Description
        map % Description
        gray % Description
        colortype
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnUpload
        function btnUploadButtonPushed(app, event)
            f=app.UIFigure;
            [filename,path]=uigetfile({'*.png';'*.jpg';'*.tif'},'Chon anh');
            if isequal(filename, 0) || isequal(path,0)
	            figure(f);
   	            uialert(f,'Chua chon anh','Loi doc anh');
            else
                figure(f);
                file=strcat(path, filename);
                [app.img, app.map] = imread(file);
                imshow(app.img, 'Parent', app.axesAnhXam);
                info = imfinfo(file);
                app.colortype = info.ColorType;
                if app.colortype=="truecolor"
                   rgb=app.img;
                   app.gray=rgb2gray(rgb);
                elseif app.colortype=="indexed"
                   ind=app.img;
                   app.gray=ind2gray(ind,app.map);
                else
                   app.gray = app.img;
                end
                imshow(app.gray,'Parent',app.axesAnhXam);
                
                [pixels,graylevers]=imhist(app.img);
                bar(app.axesHist,graylevers,pixels,0.2,"stacked","black");

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axesAnhXam = uiaxes(app.UIFigure);
            title(app.axesAnhXam, 'axesAnhXam')
            app.axesHist = uiaxes(app.UIFigure);
            title(app.axesHist, 'axesHist')
            app.btnUpload = uibutton(app.UIFigure, 'push');
            app.btnUpload.ButtonPushedFcn = createCallbackFcn(app, @btnUploadButtonPushed, true);
            app.btnUpload.Text = 'Upload';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Baitap6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end