classdef Baitap7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        btnUpload           matlab.ui.control.Button
        Slider              matlab.ui.control.Slider
        SliderLabel         matlab.ui.control.Label
        UIAxesAnhTruot      matlab.ui.control.UIAxes
        UIAxesAnhTruotHist  matlab.ui.control.UIAxes
        axesAnhGoc          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img
        bitdepth
        map
    end
    
    methods (Access = private)
        function oimg = truotToChucDo(app)
            [y,x]=size(app.img);
            oimg=zeros(y,x);
            c=app.Slider.Value;
            Lmax=2^app.bitdepth-1;
            for i=1:y
                for j=1:x
                    oimg(i,j)=app.img(i,j)+c;
                    if(oimg(i,j)>Lmax)
                        oimg(i,j)=Lmax;
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnUpload
        function btnUploadButtonPushed(app, event)
            f=app.UIFigure;
            [filename,path]=uigetfile({'*.png';'*.jpg';'*.tif'},'Chon anh');
            if isequal(filename, 0) || isequal(path,0)
	            figure(f);
   	            uialert(f,'Chua chon anh','Loi doc anh');
            else
                figure(f);
                file=strcat(path, filename);
                app.img = imread(file);
                imshow(app.img, 'Parent', app.axesAnhGoc);
                info = imfinfo(file);
                app.bitdepth = info.BitDepth;
            end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            oimg=app.truotToChucDo;
            imshow(oimg,[],'Parent',app.UIAxesAnhTruot);
            [onumberofpixel, ograyscale]=imhist(uint8(oimg));
            bar(app.UIAxesAnhTruotHist, ograyscale,onumberofpixel,0.5,"stacked","black");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axesAnhGoc = uiaxes(app.UIFigure);
            title(app.axesAnhGoc, 'axesAnhGoc')
            app.UIAxesAnhTruotHist = uiaxes(app.UIFigure);
            title(app.UIAxesAnhTruotHist, 'AnhTruotHist')
            app.UIAxesAnhTruot = uiaxes(app.UIFigure);
            title(app.UIAxesAnhTruot, 'Anh Truot')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 255];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.btnUpload = uibutton(app.UIFigure, 'push');
            app.btnUpload.ButtonPushedFcn = createCallbackFcn(app, @btnUploadButtonPushed, true);
            app.btnUpload.Text = 'Upload';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Baitap7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end