classdef Baptap3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        btnUpload   matlab.ui.control.Button
        axesRGB     matlab.ui.control.UIAxes
        axesBlue    matlab.ui.control.UIAxes
        axesGreen   matlab.ui.control.UIAxes
        axesRed     matlab.ui.control.UIAxes
        axesAnhGoc  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Description
        map % Description
        colortype % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnUpload
        function btnUploadPushed(app, event)
            f=app.UIFigure;
            [filename,path]=uigetfile({'*.png';'*.jpg';'*.tif'},'Chon anh');
            if isequal(filename, 0) || isequal(path,0)
	            figure(f);
   	            uialert(f,'Chua chon anh','Loi doc anh');
            else
                figure(f);
                file=strcat(path, filename);
                [app.img, app.map] = imread(file);
                info = imfinfo(file);
                app.colortype = info.ColorType;
                if app.colortype ~= "truecolor"
                    uialert(f,'phai chon anh RGB','loi chon anh')
                else               
                    imshow(app.img, 'Parent', app.axesAnhGoc);

                    % Xu ly RGB
                    red = app.img;
                    green = app.img;
                    blue = app.img;
    
                    red(:,:,2)=0;
                    red(:,:,3)=0;
    
                    green(:,:,1)=0;
                    green(:,:,3)=0;
    
                    blue(:,:,1)=0;
                    blue(:,:,2)=0;
    
                    imshow(red, 'Parent', app.axesRed);
                    imshow(green, 'Parent', app.axesGreen);
                    imshow(blue, 'Parent', app.axesBlue);
    
                    imgRGB = red+green+blue;
                    imshow(imgRGB, 'Parent', app.axesRGB);
                
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axesAnhGoc = uiaxes(app.UIFigure);
            title(app.axesAnhGoc, 'Ảnh Gốc')
            app.axesRed = uiaxes(app.UIFigure);
            title(app.axesRed, 'axesRed')
            app.axesGreen = uiaxes(app.UIFigure);
            title(app.axesGreen, 'axesGreen')
            app.axesBlue = uiaxes(app.UIFigure);
            title(app.axesBlue, 'axesBlue')
            zlabel(app.axesBlue, 'Z')
            app.axesRGB = uiaxes(app.UIFigure);
            title(app.axesRGB, 'AxesRGB')
            app.btnUpload = uibutton(app.UIFigure, 'push');
            app.btnUpload.ButtonPushedFcn = createCallbackFcn(app, @btnUploadPushed, true);
            app.btnUpload.Text = 'Upload';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Baptap3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end