classdef Baptap4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        btnUpload    matlab.ui.control.Button
        axesCMY      matlab.ui.control.UIAxes
        axesYellow   matlab.ui.control.UIAxes
        axesMagenta  matlab.ui.control.UIAxes
        axesCyan     matlab.ui.control.UIAxes
        axesAnhGoc   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Description
        map % Description
        colortype % Description % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnUpload
        function btnUploadButtonPushed(app, event)
            f=app.UIFigure;
            [filename,path]=uigetfile({'*.png';'*.jpg';'*.tif'},'Chon anh');
            if isequal(filename, 0) || isequal(path,0)
	            figure(f);
   	            uialert(f,'Chua chon anh','Loi doc anh');
            else
                figure(f);
                file=strcat(path, filename);
                [app.img, app.map] = imread(file);
                info = imfinfo(file);
                app.colortype = info.ColorType;
                if app.colortype ~= "truecolor"
                    uialert(f,'phai chon anh RGB','loi chon anh')
                else               
                    imshow(app.img, 'Parent', app.axesAnhGoc);
                    
                    %RGB -> CMY
                    cmy = 255 - app.img;

                    % Xu ly CMY
                    cyan = cmy;
                    magenta = cmy;
                    yello = cmy;

                    cyan(:,:,1)=0;
                    magenta(:,:,2)=0;
                    yello(:,:,3)=0;
    
                    imshow(cyan, 'Parent', app.axesCyan);
                    imshow(magenta, 'Parent', app.axesMagenta);
                    imshow(yello, 'Parent', app.axesYellow);
    
                    imgCMY = cyan+magenta+yello;
                    imshow(imgCMY, 'Parent', app.axesCMY);
                
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axesAnhGoc = uiaxes(app.UIFigure);
            title(app.axesAnhGoc, 'Ảnh Gốc')
            app.axesCyan = uiaxes(app.UIFigure);
            title(app.axesCyan, 'axesCyan')
            app.axesMagenta = uiaxes(app.UIFigure);
            title(app.axesMagenta, 'axesMagenta')
            app.axesYellow = uiaxes(app.UIFigure);
            title(app.axesYellow, 'axesYellow')
            zlabel(app.axesYellow, 'Z')
            app.axesCMY = uiaxes(app.UIFigure);
            title(app.axesCMY, 'AxesCMY')
            app.btnUpload = uibutton(app.UIFigure, 'push');
            app.btnUpload.ButtonPushedFcn = createCallbackFcn(app, @btnUploadButtonPushed, true);
            app.btnUpload.Text = 'Upload';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Baptap4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end