classdef AppTruotHist < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ButtonDocAnh           matlab.ui.control.Button
        ButtonHienHistogram    matlab.ui.control.Button
        GitrCEditFieldLabel    matlab.ui.control.Label
        EditFieldC             matlab.ui.control.NumericEditField
        ButtonTruotHist        matlab.ui.control.Button
        TRTHISTOGRAMCANHLabel  matlab.ui.control.Label
        UIAxesAnhGoc           matlab.ui.control.UIAxes
        UIAxesHistAnhGoc       matlab.ui.control.UIAxes
        UIAxesAnhTruotHist     matlab.ui.control.UIAxes
        UIAxesHistTruot        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Description
        map
        colortype
        
    end
    
    properties (Access = public)
        bitdepth % Description
    end
    
    methods (Access = private)
        
        function oimg = truotToChucDo(app)
            [y,x]=size(app.img);
            oimg=zeros(y,x);
            c=app.EditFieldC.Value;
            Lmax=2^app.bitdepth-1;
            for i=1:y
                for j=1:x
                    oimg(i,j)=app.img(i,j)+c;
                    if(oimg(i,j)>Lmax)
                        oimg(i,j)=Lmax;
                    end
                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ButtonDocAnh
        function ButtonDocAnhPushed(app, event)
            [filename, path]=uigetfile({'*.jpg';'*.png';'*.tif'},"Chọn tập tin ảnh");
            if isequal(filename,0)||isequal(path,0)
                uialert(app.UIFigure,"Chưa đọc tập tin ảnh","Lỗi đọc ảnh");
                cla(app.UIAxesAnhGoc);
            else
                file=strcat(path, filename);
                info=imfinfo(file);
                if length(info)>1
                    info=info(1);
                end
                app.colortype=info.ColorType;
                app.bitdepth=info.BitDepth;
                [app.img,app.map]=imread(file);
                if app.colortype=="truecolor"
                    app.img=rgb2gray(app.img);
                elseif app.colortype=="indexed"
                    app.img=ind2gray(app.img);
                end
                imshow(app.img,[],'Parent',app.UIAxesAnhGoc);
            end
        end

        % Button pushed function: ButtonHienHistogram
        function ButtonHienHistogramPushed(app, event)
            [numberofpixel, grayscale]=imhist(app.img);
            bar(app.UIAxesHistAnhGoc,grayscale,numberofpixel,0.5,"stacked","black");
        end

        % Button pushed function: ButtonTruotHist
        function ButtonTruotHistPushed(app, event)
            oimg=app.truotToChucDo;
            imshow(oimg,[],'Parent',app.UIAxesAnhTruotHist);
            [onumberofpixel, ograyscale]=imhist(uint8(oimg));
            bar(app.UIAxesHistTruot, ograyscale,onumberofpixel,0.5,"stacked","black");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ButtonDocAnh = uibutton(app.UIFigure, 'push');
            app.ButtonDocAnh.ButtonPushedFcn = createCallbackFcn(app, @ButtonDocAnhPushed, true);
            app.ButtonDocAnh.Text = 'Đọc ảnh';
            app.ButtonHienHistogram = uibutton(app.UIFigure, 'push');
            app.ButtonHienHistogram.ButtonPushedFcn = createCallbackFcn(app, @ButtonHienHistogramPushed, true);
            app.ButtonHienHistogram.Text = 'Hiển thị Histogram';
            app.GitrCEditFieldLabel = uilabel(app.UIFigure);
            app.GitrCEditFieldLabel.Text = 'Giá trị C:';
            app.EditFieldC = uieditfield(app.UIFigure, 'numeric');
            app.ButtonTruotHist = uibutton(app.UIFigure, 'push');
            app.ButtonTruotHist.ButtonPushedFcn = createCallbackFcn(app, @ButtonTruotHistPushed, true);
            app.ButtonTruotHist.Text = 'Trượt Histogram';
            app.TRTHISTOGRAMCANHLabel = uilabel(app.UIFigure);
            app.TRTHISTOGRAMCANHLabel.Text = 'TRƯỢT HISTOGRAM CỦA ẢNH';
            app.UIAxesAnhGoc = uiaxes(app.UIFigure);
            title(app.UIAxesAnhGoc, 'Ảnh gốc')
            app.UIAxesAnhGoc.XTick = [];
            app.UIAxesAnhGoc.YTick = [];
            app.UIAxesHistAnhGoc = uiaxes(app.UIFigure);
            title(app.UIAxesHistAnhGoc, 'Histogram ảnh gốc')
            app.UIAxesHistAnhGoc.XTick = [];
            app.UIAxesHistAnhGoc.YTick = [];
            app.UIAxesAnhTruotHist = uiaxes(app.UIFigure);
            title(app.UIAxesAnhTruotHist, 'Ảnh kết quả trượt Histogram')
            app.UIAxesAnhTruotHist.XTick = [];
            app.UIAxesAnhTruotHist.YTick = [];
            app.UIAxesHistTruot = uiaxes(app.UIFigure);
            title(app.UIAxesHistTruot, 'Kết quả trượt Histogram')
            app.UIAxesHistTruot.XTick = [];
            app.UIAxesHistTruot.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppTruotHist

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end