classdef sosanh < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UIAnhGoc            matlab.ui.control.Button
        BlcSButton          matlab.ui.control.Button
        BlcLaplacianButton  matlab.ui.control.Button
        UIAnh               matlab.ui.control.UIAxes
        UIAxes_x            matlab.ui.control.UIAxes
        UIAxes_y            matlab.ui.control.UIAxes
        UIAxes_l1           matlab.ui.control.UIAxes
        UIAnnh_2            matlab.ui.control.UIAxes
        UIAxes_l2           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        img %anh
        gray %anh xam
        map %map
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UIAnhGoc
        function UIAnhGocButtonPushed(app, event)
            [filename,path]=uigetfile({'*.tif';'*.jpg';'*.png'},'Chọn file');
            if isequal(filename,0)|| isequal(path,0)
                uialert(app.UIFigure,"Không thể đọc ảnh","Lỗi đọc ảnh");
                
            else
                file=strcat(path,filename);
                [app.img,app.map]=imread(file);
                app.gray=app.img;
                info=imfinfo(file);
                if length(info)>1
                    info=info(1);
                end
                if(info.ColorType=="truecolor")
                    app.gray=rgb2gray(app.gray);
                elseif (info.ColorType=="indexed")
                    app.gray=ind2gray(app.gray);
                end
                figure(app.UIFigure);
                imshow(app.img,'Parent',app.UIAnh);
                imshow(app.img,'Parent',app.UIAnnh_2);
            end
        end

        % Button pushed function: BlcSButton
        function BlcSButtonPushed(app, event)
            so_x=[-1 0 1; -2 0 2; -1 0 1];
            gx=filter2(so_x, app.gray);
            imshow(gx/255,'Parent',app.UIAxes_x);
            so_y =[-1 -2 -1; 0 0 0; 1 2 1] ;
            gy=filter2(so_y,app.gray);
            imshow(gy/255,'Parent',app.UIAxes_y);
        end

        % Button pushed function: BlcLaplacianButton
        function BlcLaplacianButtonPushed(app, event)
            l1=[0 1 0; 1 -4 1; 0 1 0];
            a1=filter2(l1, app.gray);
            imshow(a1/255,'Parent',app.UIAxes_l1);
            l2=[1 1 1; 1 -8 1; 1 1 1];
            a2=filter2(l2, app.gray);
            imshow(a2/255,'Parent',app.UIAxes_l2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAnhGoc = uibutton(app.UIFigure, 'push');
            app.UIAnhGoc.ButtonPushedFcn = createCallbackFcn(app, @UIAnhGocButtonPushed, true);
            app.UIAnhGoc.Text = 'Đọc ảnh';
            app.BlcSButton = uibutton(app.UIFigure, 'push');
            app.BlcSButton.ButtonPushedFcn = createCallbackFcn(app, @BlcSButtonPushed, true);
            app.BlcSButton.Text = 'Bộ lọc S';
            app.BlcLaplacianButton = uibutton(app.UIFigure, 'push');
            app.BlcLaplacianButton.ButtonPushedFcn = createCallbackFcn(app, @BlcLaplacianButtonPushed, true);
            app.BlcLaplacianButton.Text = 'Bộ lọc Laplacian';
            app.UIAnh = uiaxes(app.UIFigure);
            title(app.UIAnh, 'Ảnh gốc ')
            zlabel(app.UIAnh, 'Z')
            app.UIAnh.XTick = [];
            app.UIAnh.YTick = [];
            app.UIAxes_x = uiaxes(app.UIFigure);
            title(app.UIAxes_x, 'Ảnh theo chiều x')
            zlabel(app.UIAxes_x, 'Z')
            app.UIAxes_x.XTick = [];
            app.UIAxes_x.YTick = [];
            app.UIAxes_y = uiaxes(app.UIFigure);
            title(app.UIAxes_y, 'Ảnh theo chiều y')
            zlabel(app.UIAxes_y, 'Z')
            app.UIAxes_y.XTick = [];
            app.UIAxes_y.YTick = [];
            app.UIAxes_l1 = uiaxes(app.UIFigure);
            title(app.UIAxes_l1, 'Ảnh với bộ lọc đơn')
            zlabel(app.UIAxes_l1, 'Z')
            app.UIAxes_l1.XTick = [];
            app.UIAxes_l1.YTick = [];
            app.UIAnnh_2 = uiaxes(app.UIFigure);
            title(app.UIAnnh_2, 'Ảnh gốc ')
            zlabel(app.UIAnnh_2, 'Z')
            app.UIAnnh_2.XTick = [];
            app.UIAnnh_2.YTick = [];
            app.UIAxes_l2 = uiaxes(app.UIFigure);
            title(app.UIAxes_l2, 'Ảnh với bộ cộng thêm ')
            zlabel(app.UIAxes_l2, 'Z')
            app.UIAxes_l2.XTick = [];
            app.UIAxes_l2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sosanh

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end