classdef saltandpepper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        NHIUMUITIULabel           matlab.ui.control.Label
        docanhButton              matlab.ui.control.Button
        BlcDropDownLabel          matlab.ui.control.Label
        bl                        matlab.ui.control.DropDown
        LocanhButton              matlab.ui.control.Button
        matdonhieuEditFieldLabel  matlab.ui.control.Label
        md                        matlab.ui.control.NumericEditField
        apdungButton              matlab.ui.control.Button
        ag                        matlab.ui.control.UIAxes
        kq                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img
        map
        colortype% Description
        gray
        I
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: docanhButton
        function docanhButtonPushed(app, event)
            f=app.UIFigure;
            [filename,path]=uigetfile({'*.png';'*.jpg';'*.tif'},'Chon anh');
            if isequal(filename,0) || isequal(path,0)
                uialert(f,'Chua chon anh','Loi doc anh');
                cla(app.ag);
            else
                file=strcat(path,filename);
                [app.img,app.map]=imread(file);
                info=imfinfo(file);
                app.colortype=info.ColorType;
                if app.colortype=="truecolor"
                    app.gray=rgb2gray(app.img);
                elseif app.colortype=="indexed"    
                    app.gray=ind2gray(app.img,app.map);
                else 
                    app.gray=app.img;
                end               
                    app.gray=imnoise(app.gray,"salt & pepper",app.md.Value);          
                imshow(app.gray,'Parent',app.ag)
            end
        end

        % Button pushed function: LocanhButton
        function LocanhButtonPushed(app, event)
            if app.bl.Value=="trung vị"     
                a=medfilt2(app.I);
                imshow(a,'Parent',app.kq);
            end
        end

        % Button pushed function: apdungButton
        function apdungButtonPushed(app, event)
          
            app.I=imnoise(app.gray,"salt & pepper",app.md.Value);          
            imshow(app.I,'Parent',app.ag);
            cla(app.kq);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NHIUMUITIULabel = uilabel(app.UIFigure);
            app.NHIUMUITIULabel.Text = 'NHIỄU MUỐI TIÊU';
            app.docanhButton = uibutton(app.UIFigure, 'push');
            app.docanhButton.ButtonPushedFcn = createCallbackFcn(app, @docanhButtonPushed, true);
            app.docanhButton.Text = 'doc anh';
            app.BlcDropDownLabel = uilabel(app.UIFigure);
            app.BlcDropDownLabel.Text = 'Bộ lọc';
            app.bl = uidropdown(app.UIFigure);
            app.bl.Items = {'trung vị', 'phong thấp', 'thích nghi', 'xếp hạng thứ tự'};
            app.bl.Value = 'trung vị';
            app.LocanhButton = uibutton(app.UIFigure, 'push');
            app.LocanhButton.ButtonPushedFcn = createCallbackFcn(app, @LocanhButtonPushed, true);
            app.LocanhButton.Text = 'Loc anh';
            app.matdonhieuEditFieldLabel = uilabel(app.UIFigure);
            app.matdonhieuEditFieldLabel.Text = 'mat do nhieu';
            app.md = uieditfield(app.UIFigure, 'numeric');
            app.apdungButton = uibutton(app.UIFigure, 'push');
            app.apdungButton.ButtonPushedFcn = createCallbackFcn(app, @apdungButtonPushed, true);
            app.apdungButton.Text = 'ap dung';
            app.ag = uiaxes(app.UIFigure);
            title(app.ag, 'Ảnh gốc')
            xlabel(app.ag, 'X')
            ylabel(app.ag, 'Y')
            zlabel(app.ag, 'Z')
            app.kq = uiaxes(app.UIFigure);
            title(app.kq, 'Kết quả')
            xlabel(app.kq, 'X')
            ylabel(app.kq, 'Y')
            zlabel(app.kq, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = saltandpepper

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end