classdef xla_group1_PhucHoiAnh_Gaussian < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        THMNHIUButton        matlab.ui.control.Button
        Btn_LocAnh           matlab.ui.control.Button
        NHIUEditFieldLabel   matlab.ui.control.Label
        Field_DoNhieu        matlab.ui.control.NumericEditField
        UIAxes_LocThichNghi  matlab.ui.control.UIAxes
        UIAxes_LocTrungVi    matlab.ui.control.UIAxes
        UIAxes_ImgInput      matlab.ui.control.UIAxes
        UIAxes_Noise         matlab.ui.control.UIAxes
        Title                matlab.ui.control.Label
        Btn_UpAnh            matlab.ui.control.Button
    end

    
    properties (Access = private)
        img % Description
        colortype % Description
        gray % Description
        gray_g % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Btn_UpAnh
        function Btn_UpAnhButtonPushed(app, event)
            global fullfile;
            %[filename, filepath] = uigetfile({'*.*'}, 'Chọn file để mở...');
            %fullfile=strcat(filepath,filename);
            %app.img = imread(fullfile);
            app.img = imread('cameraman.tif');
            info=imfinfo(fullfile);
            
            % info.ColorType: Lấy kiểu màu của ảnh
            app.colortype=info.ColorType;
            
            % truecolor: Ảnh màu
            if app.colortype=="truecolor" 
                app.gray=rgb2gray(app.img);
            
            % indexed: Ảnh nhị phân
            elseif app.colortype=="indexed" 
                app.gray=ind2gray(app.img,app.map);
            
            % grayscale: Ảnh đa mức xám
            else 
                app.gray=app.img;
            end             
            imshow(app.gray,'Parent',app.UIAxes_ImgInput);
        end

        % Button pushed function: THMNHIUButton
        function THMNHIUButtonPushed(app, event)
            app.gray_g=imnoise(app.gray,"gaussian",app.Field_DoNhieu.Value);
            imshow(app.gray_g,'Parent',app.UIAxes_Noise);
        end

        % Button pushed function: Btn_LocAnh
        function Btn_LocAnhButtonPushed(app, event)
            %-Lọc trung vị (trung bình) 
            %Img_LocTrungVi=medfilt2(app.gray_g);
            A_f = fftshift(fft2(app.gray_g));
            B = A_f.*H;
            C = abs(ifft2(B));
            Img_LocTrungVi=uint8(C);
            imshow(Img_LocTrungVi,'Parent',app.UIAxes_LocTrungVi);
            
            %-Lọc thích nghi
            Img_LocThichNghi=imnoise(app.gray_g,'gaussian', 0, 0.005);
            imshow(Img_LocThichNghi,'Parent',app.UIAxes_LocThichNghi);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.THMNHIUButton = uibutton(app.GridLayout, 'push');
            app.THMNHIUButton.ButtonPushedFcn = createCallbackFcn(app, @THMNHIUButtonPushed, true);
            app.THMNHIUButton.Text = 'THÊM NHIỄU';
            app.Btn_LocAnh = uibutton(app.GridLayout, 'push');
            app.Btn_LocAnh.ButtonPushedFcn = createCallbackFcn(app, @Btn_LocAnhButtonPushed, true);
            app.Btn_LocAnh.Text = 'LỌC ẢNH';
            app.NHIUEditFieldLabel = uilabel(app.GridLayout);
            app.NHIUEditFieldLabel.Text = 'ĐỘ NHIỄU:';
            app.Field_DoNhieu = uieditfield(app.GridLayout, 'numeric');
            app.UIAxes_LocThichNghi = uiaxes(app.GridLayout);
            title(app.UIAxes_LocThichNghi, 'Lọc thích nghi')
            xlabel(app.UIAxes_LocThichNghi, 'X')
            ylabel(app.UIAxes_LocThichNghi, 'Y')
            zlabel(app.UIAxes_LocThichNghi, 'Z')
            app.UIAxes_LocTrungVi = uiaxes(app.GridLayout);
            title(app.UIAxes_LocTrungVi, 'Lọc trung vị (trung bình)')
            xlabel(app.UIAxes_LocTrungVi, 'X')
            ylabel(app.UIAxes_LocTrungVi, 'Y')
            zlabel(app.UIAxes_LocTrungVi, 'Z')
            app.UIAxes_ImgInput = uiaxes(app.GridLayout);
            title(app.UIAxes_ImgInput, 'Ảnh đầu vào')
            xlabel(app.UIAxes_ImgInput, 'X')
            ylabel(app.UIAxes_ImgInput, 'Y')
            zlabel(app.UIAxes_ImgInput, 'Z')
            app.UIAxes_Noise = uiaxes(app.GridLayout);
            title(app.UIAxes_Noise, 'Ảnh nhiễu (Noise)')
            xlabel(app.UIAxes_Noise, 'X')
            ylabel(app.UIAxes_Noise, 'Y')
            zlabel(app.UIAxes_Noise, 'Z')
            app.Title = uilabel(app.GridLayout);
            app.Title.Text = 'PHỤC HỒI ẢNH - NHIỄU GAUSSIAN';
            app.Btn_UpAnh = uibutton(app.GridLayout, 'push');
            app.Btn_UpAnh.ButtonPushedFcn = createCallbackFcn(app, @Btn_UpAnhButtonPushed, true);
            app.Btn_UpAnh.Text = 'Tải ảnh lên';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = xla_group1_PhucHoiAnh_Gaussian

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end